classdef Forced_Vibration_with_Damping_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DynamicsofMachineryUIFigure  matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        FSliderLabel                 matlab.ui.control.Label
        FSlider                      matlab.ui.control.Slider
        SliderLabel                  matlab.ui.control.Label
        HFAngularFrequency           matlab.ui.control.Slider
        ForceNLabel                  matlab.ui.control.Label
        AngularFrequencyradLabel     matlab.ui.control.Label
        mSliderLabel                 matlab.ui.control.Label
        mSlider                      matlab.ui.control.Slider
        MasskgLabel                  matlab.ui.control.Label
        ASliderLabel                 matlab.ui.control.Label
        ASlider                      matlab.ui.control.Slider
        InitialAmplitudemLabel       matlab.ui.control.Label
        DynamicsofMachineryLabel     matlab.ui.control.Label
        ForcedVibrationwithDampingLabel  matlab.ui.control.Label
        ShubhamWaniNITTiruchirappalliLabel  matlab.ui.control.Label
        ParametersLabel              matlab.ui.control.Label
        DampingRatioLabel            matlab.ui.control.Label
        SliderLabel_2                matlab.ui.control.Label
        ZetaSlider                   matlab.ui.control.Slider
        SpringConstantNmLabel        matlab.ui.control.Label
        kSliderLabel                 matlab.ui.control.Label
        kSlider                      matlab.ui.control.Slider
        UIAxes_2                     matlab.ui.control.UIAxes
        Label                        matlab.ui.control.Label
    end


    methods (Access = private)
    
        function func(app)
            F = app.FSlider.Value;
            w = app.HFAngularFrequency.Value;
            m = app.mSlider.Value;
            A = app.ASlider.Value;
            z = app.ZetaSlider.Value;
            k = app.kSlider.Value;
            t = 0:0.01:10;
            wn=sqrt(k/m);
            r=w/wn;
            transient = A*exp(-z*wn*t).*cos(sqrt(1-z^2)*wn*t);
            steady=(F)*sin(w*t+atan((-2*z*r)/(1-r^2)))./(k*sqrt((1-z^2)^2+(2*m*k)^2));
            total= steady+transient;
            plot(app.UIAxes,t,total);
            rp=0:0.01:2.5;
            mf=1./(sqrt((1-rp.^2).^2+(2*z*rp).^2));
            ph=atan((-2*z*rp)./(1-rp.^2));
            plot(app.UIAxes_2,rp,mf,'-r');
            hold(app.UIAxes_2);
            plot(app.UIAxes_2,rp,ph,'-b');
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.FSlider,'Value',2)
            set(app.HFAngularFrequency,'Value',3.1415)
            set(app.mSlider,'Value',0.4)
            set(app.ASlider,'Value',1)
            set(app.ZetaSlider,'Value',0.2)
            set(app.kSlider,'Value',3)
            func(app)
        end

        % Value changed function: FSlider
        function FSliderValueChanged(app, event)
            func(app)
        end

        % Value changed function: HFAngularFrequency
        function HFAngularFrequencyValueChanged(app, event)
            func(app)
        end

        % Value changed function: mSlider
        function mSliderValueChanged(app, event)
            func(app)
        end

        % Value changed function: ASlider
        function ASliderValueChanged(app, event)
            func(app)
        end

        % Value changed function: ZetaSlider
        function ZetaSliderValueChanged(app, event)
            func(app)
        end

        % Value changed function: kSlider
        function kSliderValueChanged(app, event)
            func(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DynamicsofMachineryUIFigure = uifigure;
            app.DynamicsofMachineryUIFigure.Name = 'Dynamics of Machinery';
            app.UIAxes = uiaxes(app.DynamicsofMachineryUIFigure);
            title(app.UIAxes, 'Total Response')
            xlabel(app.UIAxes, 'Time(t)')
            ylabel(app.UIAxes, 'X(t)')
            app.FSliderLabel = uilabel(app.DynamicsofMachineryUIFigure);
            app.FSliderLabel.Text = 'F';
            app.FSlider = uislider(app.DynamicsofMachineryUIFigure);
            app.FSlider.Limits = [0 5];
            app.FSlider.ValueChangedFcn = createCallbackFcn(app, @FSliderValueChanged, true);
            app.SliderLabel = uilabel(app.DynamicsofMachineryUIFigure);
            app.SliderLabel.Text = 'ω';
            app.HFAngularFrequency = uislider(app.DynamicsofMachineryUIFigure);
            app.HFAngularFrequency.Limits = [0 15.7];
            app.HFAngularFrequency.MajorTicks = [0 3.14 6.28 9.42 12.56 15.7];
            app.HFAngularFrequency.ValueChangedFcn = createCallbackFcn(app, @HFAngularFrequencyValueChanged, true);
            app.ForceNLabel = uilabel(app.DynamicsofMachineryUIFigure);
            app.ForceNLabel.Text = 'Force(N)';
            app.AngularFrequencyradLabel = uilabel(app.DynamicsofMachineryUIFigure);
            app.AngularFrequencyradLabel.Text = 'Angular Frequency(rad)';
            app.mSliderLabel = uilabel(app.DynamicsofMachineryUIFigure);
            app.mSliderLabel.Text = 'm';
            app.mSlider = uislider(app.DynamicsofMachineryUIFigure);
            app.mSlider.Limits = [0 8];
            app.mSlider.ValueChangedFcn = createCallbackFcn(app, @mSliderValueChanged, true);
            app.MasskgLabel = uilabel(app.DynamicsofMachineryUIFigure);
            app.MasskgLabel.Text = 'Mass(kg)';
            app.ASliderLabel = uilabel(app.DynamicsofMachineryUIFigure);
            app.ASliderLabel.Text = 'A';
            app.ASlider = uislider(app.DynamicsofMachineryUIFigure);
            app.ASlider.Limits = [0 2];
            app.ASlider.MajorTicks = [0 0.5 1 1.5 2];
            app.ASlider.ValueChangedFcn = createCallbackFcn(app, @ASliderValueChanged, true);
            app.InitialAmplitudemLabel = uilabel(app.DynamicsofMachineryUIFigure);
            app.InitialAmplitudemLabel.Text = 'Initial Amplitude(m)';
            app.DynamicsofMachineryLabel = uilabel(app.DynamicsofMachineryUIFigure);
            app.DynamicsofMachineryLabel.Text = 'Dynamics of Machinery';
            app.ForcedVibrationwithDampingLabel = uilabel(app.DynamicsofMachineryUIFigure);
            app.ForcedVibrationwithDampingLabel.Text = 'Forced Vibration with Damping';
            app.ShubhamWaniNITTiruchirappalliLabel = uilabel(app.DynamicsofMachineryUIFigure);
            app.ShubhamWaniNITTiruchirappalliLabel.Text = 'Shubham Wani, NIT Tiruchirappalli';
            app.ParametersLabel = uilabel(app.DynamicsofMachineryUIFigure);
            app.ParametersLabel.Text = 'Parameters';
            app.DampingRatioLabel = uilabel(app.DynamicsofMachineryUIFigure);
            app.DampingRatioLabel.Text = 'Damping Ratio';
            app.SliderLabel_2 = uilabel(app.DynamicsofMachineryUIFigure);
            app.SliderLabel_2.Text = 'ζ';
            app.ZetaSlider = uislider(app.DynamicsofMachineryUIFigure);
            app.ZetaSlider.Limits = [0 1];
            app.ZetaSlider.ValueChangedFcn = createCallbackFcn(app, @ZetaSliderValueChanged, true);
            app.SpringConstantNmLabel = uilabel(app.DynamicsofMachineryUIFigure);
            app.SpringConstantNmLabel.Text = 'Spring Constant(N/m)';
            app.kSliderLabel = uilabel(app.DynamicsofMachineryUIFigure);
            app.kSliderLabel.Text = 'k';
            app.kSlider = uislider(app.DynamicsofMachineryUIFigure);
            app.kSlider.Limits = [0 5];
            app.kSlider.ValueChangedFcn = createCallbackFcn(app, @kSliderValueChanged, true);
            app.UIAxes_2 = uiaxes(app.DynamicsofMachineryUIFigure);
            title(app.UIAxes_2, 'Magnification Factor(Red) and Phase Angle(Blue)')
            xlabel(app.UIAxes_2, 'Frequency Ratio(r)')
            app.Label = uilabel(app.DynamicsofMachineryUIFigure);
            app.Label.Text = '111116086';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Forced_Vibration_with_Damping_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DynamicsofMachineryUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DynamicsofMachineryUIFigure)
        end
    end
end